classdef main_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DrivingDirectionSwitch        matlab.ui.control.Switch
        DrivingDirectionSwitchLabel   matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        StatusLabelTxt                matlab.ui.control.Label
        ResetGyrosensorButton         matlab.ui.control.Button
        StopPlatformBalancingButton   matlab.ui.control.Button
        StartPlatformBalancingButton  matlab.ui.control.Button
        PauseDrivingButton            matlab.ui.control.Button
        maxRotationsSpinner           matlab.ui.control.Spinner
        MaxRotations0100SpinnerLabel  matlab.ui.control.Label
        accelerationSpinner           matlab.ui.control.Spinner
        Acceleration05SpinnerLabel    matlab.ui.control.Label
        InitialDrivingSpeedSpinner    matlab.ui.control.Spinner
        InitialDrivingSpeedSpinnerLabel  matlab.ui.control.Label
        decelerationSpeedSpinner      matlab.ui.control.Spinner
        DecelerationSpeed0110SpinnerLabel  matlab.ui.control.Label
        titleLabel                    matlab.ui.control.Label
        distanceSpinner               matlab.ui.control.Spinner
        DistancetostopbeforeobstaclemSpinnerLabel  matlab.ui.control.Label
        StartRoboterButton            matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        logicController;
        robotTimer
    end
    
    properties (Access = public)
        config Config
        eventQueue parallel.pool.DataQueue
    end
    
    methods (Access = public)
        
        function shutdownApp(app)
            delete(app);
        end
        
        function setComponentsEnabled(app, enable)
            app.InitialDrivingSpeedSpinner.Enable=enable;
            app.distanceSpinner.Enable=enable;
            app.decelerationSpeedSpinner.Enable=enable;
            app.accelerationSpinner.Enable=enable;
            app.maxRotationsSpinner.Enable=enable;
            app.DrivingDirectionSwitch.Enable=enable;

            app.StartRoboterButton.Enable=enable;
        end
        function setPauseEnabled(app, enable)
            app.PauseDrivingButton.Enable=enable;
        end
    end


    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.config=Config();
            app.logicController=RealRobotLogicController(app.config);
            try
                app.logicController.initialize();
            catch exception
                disp(getReport(exception));
               selection= uiconfirm(app.UIFigure, strcat("Failed to initialize robot: ",exception.message),"Robot initialization failed", "Icon","error", ...
                    'Options', {'Retry','Cancel'}, 'DefaultOption',1, 'CancelOption',2);
               if(strcmp(selection, 'Retry'))
                   startupFcn(app);
               else
                   shutdownApp(app);
               end
            end
            app.robotTimer=timer("ExecutionMode","fixedRate", "Period", 0.05, "TimerFcn", @(~,~)app.logicController.robotStep());

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           app.logicController.shutdown();
            shutdownApp(app);
        end

        % Value changed function: distanceSpinner
        function distanceSpinnerValueChanged(app, event)
            value = app.distanceSpinner.Value;
            app.config.minDistance=value;
        end

        % Value changed function: decelerationSpeedSpinner
        function decelerationSpeedSpinnerValueChanged(app, event)
            value = app.decelerationSpeedSpinner.Value;
            app.config.deceleration=value;
        end

        % Value changed function: InitialDrivingSpeedSpinner
        function InitialDrivingSpeedSpinnerValueChanged(app, event)
            value = app.InitialDrivingSpeedSpinner.Value;
            app.config.initialFrontDriveSpeed=value;
        end

        % Value changed function: accelerationSpinner
        function accelerationSpinnerValueChanged(app, event)
            value = app.accelerationSpinner.Value;
            app.config.acceleration=value;
        end

        % Value changed function: maxRotationsSpinner
        function maxRotationsSpinnerValueChanged(app, event)
            value = app.maxRotationsSpinner.Value;
            app.config.maxRotations=value;
            
        end

        % Button pushed function: StartRoboterButton
        function StartRoboterButtonPushed(app, event)
            app.PauseDrivingButton.Enable=true;
            app.setComponentsEnabled(false);
            
            f=app.logicController.startRobot();

            afterEach(f, @(~) app.setComponentsEnabled(true), 0);
            afterEach(f, @(~) app.setPauseEnabled(false), 0);
        end

        % Value changed function: DrivingDirectionSwitch
        function DrivingDirectionSwitchValueChanged(app, event)
            value = app.DrivingDirectionSwitch.Value;
            
            if strcmp(value, 'Frontward')
                app.logicController.direction=1;
            else
                app.logicController.direction=-1;
            end
        end

        % Button pushed function: ResetGyrosensorButton, 
        % ...and 1 other component
        function StartPlatformBalancingButtonPushed(app, event)
            try
                app.logicController.start_gyrosensor(app);  %%% FIXME this doesnt actually start the platform balancing, but only the gyro reset init stuff
            catch exc
                uialert(app.UIFigure, "The gyrosensor seems to drift!", 'Error');
            end
        end

        % Button pushed function: StopPlatformBalancingButton
        function StopPlatformBalancingButtonPushed(app, event)
            app.logicController.stop_gyrosensor();
        end

        % Button pushed function: PauseDrivingButton
        function PauseDrivingButtonPushed(app, event)
            app.logicController.pause();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartRoboterButton = uibutton(app.UIFigure, 'push');
            app.StartRoboterButton.ButtonPushedFcn = createCallbackFcn(app, @StartRoboterButtonPushed, true);
            app.StartRoboterButton.Text = 'Start Roboter';
            app.DistancetostopbeforeobstaclemSpinnerLabel = uilabel(app.UIFigure);
            app.DistancetostopbeforeobstaclemSpinnerLabel.Text = 'Distance to stop before obstacle [m]';
            app.distanceSpinner = uispinner(app.UIFigure);
            app.distanceSpinner.Step = 0.1;
            app.distanceSpinner.Limits = [0 1];
            app.distanceSpinner.ValueChangedFcn = createCallbackFcn(app, @distanceSpinnerValueChanged, true);
            app.distanceSpinner.Value = 0.2;
            app.titleLabel = uilabel(app.UIFigure);
            app.titleLabel.Text = 'EV 3 Bar Bot Configuration and Management';
            app.DecelerationSpeed0110SpinnerLabel = uilabel(app.UIFigure);
            app.DecelerationSpeed0110SpinnerLabel.Text = 'Deceleration Speed [0.1-10]';
            app.decelerationSpeedSpinner = uispinner(app.UIFigure);
            app.decelerationSpeedSpinner.Step = 0.1;
            app.decelerationSpeedSpinner.Limits = [0.1 10];
            app.decelerationSpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @decelerationSpeedSpinnerValueChanged, true);
            app.decelerationSpeedSpinner.Value = 1;
            app.InitialDrivingSpeedSpinnerLabel = uilabel(app.UIFigure);
            app.InitialDrivingSpeedSpinnerLabel.Text = 'Initial Driving Speed';
            app.InitialDrivingSpeedSpinner = uispinner(app.UIFigure);
            app.InitialDrivingSpeedSpinner.Limits = [0 100];
            app.InitialDrivingSpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @InitialDrivingSpeedSpinnerValueChanged, true);
            app.InitialDrivingSpeedSpinner.Value = 30;
            app.Acceleration05SpinnerLabel = uilabel(app.UIFigure);
            app.Acceleration05SpinnerLabel.Text = 'Acceleration [0-5]';
            app.accelerationSpinner = uispinner(app.UIFigure);
            app.accelerationSpinner.Step = 0.1;
            app.accelerationSpinner.Limits = [0 5];
            app.accelerationSpinner.ValueChangedFcn = createCallbackFcn(app, @accelerationSpinnerValueChanged, true);
            app.accelerationSpinner.Value = 0.4;
            app.MaxRotations0100SpinnerLabel = uilabel(app.UIFigure);
            app.MaxRotations0100SpinnerLabel.Text = 'Max. Rotations [0-100]';
            app.maxRotationsSpinner = uispinner(app.UIFigure);
            app.maxRotationsSpinner.Limits = [0 100];
            app.maxRotationsSpinner.ValueChangedFcn = createCallbackFcn(app, @maxRotationsSpinnerValueChanged, true);
            app.maxRotationsSpinner.Value = 20;
            app.PauseDrivingButton = uibutton(app.UIFigure, 'push');
            app.PauseDrivingButton.ButtonPushedFcn = createCallbackFcn(app, @PauseDrivingButtonPushed, true);
            app.PauseDrivingButton.Enable = 'off';
            app.PauseDrivingButton.Text = 'Pause Driving';
            app.StartPlatformBalancingButton = uibutton(app.UIFigure, 'push');
            app.StartPlatformBalancingButton.ButtonPushedFcn = createCallbackFcn(app, @StartPlatformBalancingButtonPushed, true);
            app.StartPlatformBalancingButton.Text = 'Start Platform Balancing';
            app.StopPlatformBalancingButton = uibutton(app.UIFigure, 'push');
            app.StopPlatformBalancingButton.ButtonPushedFcn = createCallbackFcn(app, @StopPlatformBalancingButtonPushed, true);
            app.StopPlatformBalancingButton.Text = 'Stop Platform Balancing';
            app.ResetGyrosensorButton = uibutton(app.UIFigure, 'push');
            app.ResetGyrosensorButton.ButtonPushedFcn = createCallbackFcn(app, @StartPlatformBalancingButtonPushed, true);
            app.ResetGyrosensorButton.Text = 'Reset Gyrosensor';
            app.StatusLabelTxt = uilabel(app.UIFigure);
            app.StatusLabelTxt.Text = 'Status: ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Initialized';
            app.DrivingDirectionSwitchLabel = uilabel(app.UIFigure);
            app.DrivingDirectionSwitchLabel.Text = 'Driving Direction';
            app.DrivingDirectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.DrivingDirectionSwitch.Items = {'Forward', 'Backward'};
            app.DrivingDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DrivingDirectionSwitchValueChanged, true);
            app.DrivingDirectionSwitch.Value = 'Forward';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_view

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end